classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label_4   matlab.ui.control.Label
        Label_3   matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        Label     matlab.ui.control.Label
        Button_4  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Image     matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [500, 360, 640, 480];  % [左, 下, 宽, 高]
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            delete(app.UIFigure);  % 关闭当前 UI 主窗口（UIFigure 是 App Designer 默认的主窗口对象）
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 关闭当前 app1 的主窗口
            delete(app.UIFigure);
            
            % 启动 app2，并调整窗口位置（可选）
            app2;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app.UIFigure);
            
            % 启动 app2，并调整窗口位置（可选）
            app3;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            delete(app.UIFigure);
            
            % 启动 app2，并调整窗口位置（可选）
            app4;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'pic_1.jpg';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'pic_5.jpeg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'pic_5.jpeg';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'pic_5.jpeg';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'pic_5.jpeg';
            app.Button_4.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '起';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '承';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '转';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '合';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end