classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Quit_2       matlab.ui.control.Button
        TextArea     matlab.ui.control.TextArea
        Label_4      matlab.ui.control.Label
        Button_4     matlab.ui.control.Button
        DropDown_2   matlab.ui.control.DropDown
        Label_3      matlab.ui.control.Label
        Vis_3        matlab.ui.control.TextArea
        Algorithm    matlab.ui.control.DropDown
        Label_2      matlab.ui.control.Label
        Vis_2        matlab.ui.control.TextArea
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button_3     matlab.ui.control.RadioButton
        Button_2     matlab.ui.control.RadioButton
        Return       matlab.ui.control.Button
        Quit         matlab.ui.control.Button
        Vis          matlab.ui.control.TextArea
        DropDown     matlab.ui.control.DropDown
        Label        matlab.ui.control.Label
        Button       matlab.ui.control.Button
        Image        matlab.ui.control.Image
    end

    
    properties (Access = public)
        select_option = 1;% Description
        mode_option = '/';
        algorithm = '/';
        obstacle_num = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [500, 360, 640, 480];  % [左, 下, 宽, 高]
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if strcmp(app.mode_option,'/')
                app.TextArea.Value = '请为运动仿真选择一种运动模式';
                return ;
            end
            app.TextArea.Value = '';
            file_address = ['C:\Users\maircal\Desktop\毕设\UE_ADLdatabase-master\ADL017\ADL017',app.mode_option,'1angles.csv'];
            motion_mode(file_address);
        end

        % Value changed function: Vis
        function VisValueChanged(app, event)
            value = app.Vis.Value;
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.obstacle_num = app.DropDown.Value;
            app.Vis.Value = app.obstacle_num;
        end

        % Button pushed function: Return
        function ReturnButtonPushed(app, event)
            % 关闭当前 app1 的主窗口
            delete(app.UIFigure);
            
            % 启动 app2，并调整窗口位置（可选）
            app1;
        end

        % Button pushed function: Quit
        function QuitButtonPushed(app, event)
            % 关闭当前 app1 的主窗口
            delete(app.UIFigure);
        end

        % Value changed function: Algorithm
        function AlgorithmValueChanged(app, event)
            app.algorithm = app.Algorithm.Value;
            app.Vis_2.Value = app.algorithm ;
        end

        % Value changed function: Vis_2
        function Vis_2ValueChanged(app, event)
            value = app.Vis_2.Value;
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            app.mode_option = app.DropDown_2.Value;
            app.Vis_3.Value = app.mode_option;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % 获取当前选中的单选按钮对象
            selected_button = app.ButtonGroup.SelectedObject;
            
            % 根据选中按钮的 Text 属性判断选择
            if strcmp(selected_button.Text, '自由轨迹')
                app.select_option = 1;
            elseif strcmp(selected_button.Text, '避障轨迹')
                app.select_option = 2;
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if strcmp(app.mode_option,'/')
                app.TextArea.Value = '请为运动仿真选择一种运动模式';
                return ;
            end
            app.TextArea.Value = '';
            str = app.mode_option;
            if app.select_option == 1
                disp('自由轨迹');
                run('P2P_Visualization.m');
            else
                disp('避障轨迹');
                if strcmp(app.algorithm, '/')
                    app.TextArea.Value = '请为避障轨迹选择合适的参数。';
                    return ;
                end
                obstacle_num = str2num(app.obstacle_num);
                if strcmp(app.algorithm,'RRT')
                    run('RRT.m');
                elseif strcmp(app.algorithm,'kd_RRT')
                    run('kd_tree_RRT.m');
                elseif strcmp(app.algorithm,'RRT*')
                    run('RRT_pro.m');
                elseif strcmp(app.algorithm,'RRT_Con')
                    run('RRT_Connect.m');
                elseif strcmp(app.algorithm,'TRRT')
                    run("TRRT.m");
                elseif strcmp(app.algorithm,'TSQP')
                    run('TSQP.m');
                else 
                    app.TextArea.Value = '未知错误，物理学不存在了';
                end
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
        end

        % Button pushed function: Quit_2
        function Quit_2ButtonPushed(app, event)
            clear; close all; clc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'pic_2.jpeg';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '运动仿真';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '障碍物个数';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'1', '2', '3', '4'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1';
            app.Vis = uitextarea(app.UIFigure);
            app.Vis.ValueChangedFcn = createCallbackFcn(app, @VisValueChanged, true);
            app.Vis.Value = {'1'};
            app.Quit = uibutton(app.UIFigure, 'push');
            app.Quit.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.Quit.Text = '退出';
            app.Return = uibutton(app.UIFigure, 'push');
            app.Return.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.Return.Text = '返回';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '自由轨迹';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '避障轨迹';
            app.Vis_2 = uitextarea(app.UIFigure);
            app.Vis_2.ValueChangedFcn = createCallbackFcn(app, @Vis_2ValueChanged, true);
            app.Vis_2.Value = {'/'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'避障算法'; ''};
            app.Algorithm = uidropdown(app.UIFigure);
            app.Algorithm.Items = {'/', 'RRT', 'kd_RRT', 'RRT*', 'RRT_Con', 'TRRT', 'TSQP'};
            app.Algorithm.ValueChangedFcn = createCallbackFcn(app, @AlgorithmValueChanged, true);
            app.Algorithm.Value = '/';
            app.Vis_3 = uitextarea(app.UIFigure);
            app.Vis_3.Value = {'/'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '运动模式';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'/', 'OL', 'CL', 'FL', 'DL'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '/';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '轨迹生成';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '提示框';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.Quit_2 = uibutton(app.UIFigure, 'push');
            app.Quit_2.ButtonPushedFcn = createCallbackFcn(app, @Quit_2ButtonPushed, true);
            app.Quit_2.Text = '清除';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end