classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Quit_2         matlab.ui.control.Button
        Quit           matlab.ui.control.Button
        btnPlayPause   matlab.ui.control.Button
        btnSelectFile  matlab.ui.control.Button
        Image          matlab.ui.control.Image
        VideoAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        videoReader % 视频读取对象
        timerObj    % 定时器（控制播放速度）
        isPlaying = false % 播放状态标志
    end
    
    methods (Access = public)
        function updateFrame(app, src, event)
            if app.videoReader.CurrentTime < app.videoReader.Duration
                frame = readFrame(app.videoReader);
                imshow(frame, 'Parent', app.VideoAxes);
            else
                stop(app.timerObj); % 播放结束自动停止
                app.isPlaying = false;
                app.btnPlayPause.Text = '播放';
                warning('视频播放完毕！');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [500, 360, 640, 480];  % [左, 下, 宽, 高]
        end

        % Button pushed function: btnSelectFile
        function btnSelectFileButtonPushed(app, event)
            [filename, path] = uigetfile({'*.mp4;*.avi;*.mov','波士顿动力宇树.mp4'}, '选择视频');
            if filename ~= 0
                app.videoReader = VideoReader(fullfile(path, filename));
                % 显示第一帧
                frame = read(app.videoReader, 1);
                imshow(frame, 'Parent', app.VideoAxes);
                app.btnPlayPause.Text = '播放';
                app.isPlaying = false;
            end
        end

        % Button pushed function: btnPlayPause
        function btnPlayPauseButtonPushed(app, event)
            if isempty(app.videoReader)
            warning('请先选择视频文件！');
            return;
            end
    
            if ~app.isPlaying
                % 创建定时器（按视频帧率播放）
                app.timerObj = timer('ExecutionMode', 'fixedRate', ...
                                     'Period', 1/app.videoReader.FrameRate, ...
                                     'TimerFcn', @(src,evnt) app.updateFrame(src,evnt));
                start(app.timerObj);
                app.btnPlayPause.Text = '暂停';
                app.isPlaying = true;
            else
                stop(app.timerObj);
                app.btnPlayPause.Text = '播放';
                app.isPlaying = false;
            end
        end

        % Button pushed function: Quit
        function QuitButtonPushed(app, event)
            delete(app.UIFigure);  % 关闭当前 UI 主窗口（UIFigure 是 App Designer 默认的主窗口对象）
        end

        % Button pushed function: Quit_2
        function Quit_2ButtonPushed(app, event)
            delete(app.UIFigure);  % 关闭当前 UI 主窗口（UIFigure 是 App Designer 默认的主窗口对象）
            app1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VideoAxes = uiaxes(app.UIFigure);
            zlabel(app.VideoAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'pic_4.jpg';
            app.btnSelectFile = uibutton(app.UIFigure, 'push');
            app.btnSelectFile.ButtonPushedFcn = createCallbackFcn(app, @btnSelectFileButtonPushed, true);
            app.btnSelectFile.Text = '选择视频';
            app.btnPlayPause = uibutton(app.UIFigure, 'push');
            app.btnPlayPause.ButtonPushedFcn = createCallbackFcn(app, @btnPlayPauseButtonPushed, true);
            app.btnPlayPause.Text = '播放';
            app.Quit = uibutton(app.UIFigure, 'push');
            app.Quit.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.Quit.Text = '退出';
            app.Quit_2 = uibutton(app.UIFigure, 'push');
            app.Quit_2.ButtonPushedFcn = createCallbackFcn(app, @Quit_2ButtonPushed, true);
            app.Quit_2.Text = '返回';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end