classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_4  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Label_2   matlab.ui.control.Label
        Button    matlab.ui.control.Button
        Image     matlab.ui.control.Image
    end

    
    properties (Access = public)
        PlayButton        matlab.ui.control.Button  % 播放按钮
        StopButton        matlab.ui.control.Button  % 停止按钮
        player            audioplayer             % 音频播放器对象（关键属性）
    end
    
    methods (Access = public)
        function [UIFigure, PlayButton, StopButton] = init(app)
            % 主窗口
            UIFigure = uifigure('Name', '音频播放器', 'Position', [100 100 400 200]);
            
            % 播放按钮
            PlayButton = uibutton(UIFigure, 'push', 'Text', '选择并播放', ...
                'Position', [100 100 120 40], 'ButtonPushedFcn', createCallbackFcn(app, @PlayButtonPushed, true));
            
            % 停止按钮
            StopButton = uibutton(UIFigure, 'push', 'Text', '停止播放', ...
                'Position', [230 100 120 40], 'ButtonPushedFcn', createCallbackFcn(app, @StopButtonPushed, true));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [500, 360, 640, 480];  % [左, 下, 宽, 高]
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             % 关闭当前 app1 的主窗口
            delete(app.UIFigure);
            
            % 启动 app2，并调整窗口位置（可选）
            app1;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % 打开文件选择对话框（仅选择 wav 文件）
            [filename, path] = uigetfile({'*.mp3;*.m4a';'*.*'}, '选择音频文件');
            if isequal(filename,0) || isequal(path,0)
                return; % 用户取消选择
            end
            fullpath = fullfile(path, filename);

            % 读取音频数据
            [y, Fs] = audioread(fullpath);
            
            % 创建并播放音频播放器
            app.player = audioplayer(y, Fs);
            play(app.player);
            disp('音频播放开始...');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if ~isempty(app.player) && isvalid(app.player)
                stop(app.player);
                disp('音频播放已停止');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'pic_3.png';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'返回'; ''};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '生活不止眼前的苟且，还有诗和远方。';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = {'播放音乐'; ''};
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '暂停音乐';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '退出';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end